classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Qimm3sSlider       matlab.ui.control.Slider
        Qimm3sSliderLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function drawTank(app)
            ax = app.UIAxes;
            tank_x = [0.2 0.7 0.7 0.2 0.2];
            tank_y = [0 0 0.6 0.6 0];
            plot(ax, tank_x, tank_y, 'LineWidth', 2.0, 'Color', 'black');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawTank(app);
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [-0.1 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Qimm3sSliderLabel = uilabel(app.GridLayout);
            app.Qimm3sSliderLabel.Text = 'Qi (mm^3/s)';
            app.Qimm3sSlider = uislider(app.GridLayout);
            app.Qimm3sSlider.Limits = [0 200];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end